classdef ddos_track < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NumberofDataSetsSliderLabel  matlab.ui.control.Label
        NumberofDataSetsSlider       matlab.ui.control.Slider
        DataTextAreaLabel            matlab.ui.control.Label
        DataTextArea                 matlab.ui.control.TextArea
        RandomizedSwitchLabel        matlab.ui.control.Label
        RandomizedSwitch             matlab.ui.control.Switch
        ShowMaxMinSwitchLabel        matlab.ui.control.Label
        ShowMaxMinSwitch             matlab.ui.control.Switch
        StartButton                  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Scrape the url for source and destination addresses
            html = string(webread('https://liben002.github.io/delete.html'));
            pattern1 = '<Data Name="SourceAddress">';
            pattern2 = '</Data>';
            srcaddress = extractBetween(html, pattern1, pattern2);
            pattern1 = '<Data Name="DestAddress">';
            dstaddress = extractBetween(html, pattern1, pattern2);
            dat(1) = struct('src', srcaddress(1), 'dst', dstaddress(1));
            for i = 2:length(dstaddress)
                dat(i).src = srcaddress(i);
                dat(i).dst = dstaddress(i);
            end
            
            % Print out the source and destination addresses to the text area
            % updating each time
            val = sprintf('Writing to File...\nSource IP   Destination IP\n%s %s\n',dat(i).src,dat(i).src);
            app.DataTextArea.Value = val;   
            for i = 1:500
                drawnow
                val = sprintf('Writing to File...\nSource IP   Destination IP\n%s %s\n',dat(i).src,dat(i).dst);
                app.DataTextArea.Value = val;
            end
            app.DataTextArea.Value = 'Finished Writing to File';
            
            % Write the source and destination addresses to a file
            file = fopen("ips.dat", "w");
            for i = 1:length(dat)
                fprintf(file, "%s %s\n", dat(i).src, dat(i).dst);
            end
            fclose(file);
            
        end

        % Callback function
        function DataTextAreaValueChanged(app, event)
            value = app.DataTextArea.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofDataSetsSliderLabel = uilabel(app.UIFigure);
            app.NumberofDataSetsSliderLabel.Text = 'Number of Data Sets';
            app.NumberofDataSetsSlider = uislider(app.UIFigure);
            app.NumberofDataSetsSlider.Limits = [100000 200000];
            app.NumberofDataSetsSlider.MajorTicks = [100000 150000 200000];
            app.NumberofDataSetsSlider.Value = 100000;
            app.DataTextAreaLabel = uilabel(app.UIFigure);
            app.DataTextAreaLabel.Text = 'Data';
            app.DataTextArea = uitextarea(app.UIFigure);
            app.RandomizedSwitchLabel = uilabel(app.UIFigure);
            app.RandomizedSwitchLabel.Text = 'Randomized';
            app.RandomizedSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowMaxMinSwitchLabel = uilabel(app.UIFigure);
            app.ShowMaxMinSwitchLabel.Text = 'Show Max/Min';
            app.ShowMaxMinSwitch = uiswitch(app.UIFigure, 'slider');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ddos_track

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end